classdef Audio_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        freq_popup        matlab.ui.control.Button
        time_popup        matlab.ui.control.Button
        ClearallButton    matlab.ui.control.Button
        StatusLabel       matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        addLamp           matlab.ui.control.Lamp
        AddButton         matlab.ui.control.Button
        stopsoundLabel    matlab.ui.control.Label
        soundOffButton    matlab.ui.control.Button
        PlotButton        matlab.ui.control.Button
        playsoundLabel    matlab.ui.control.Label
        soundOnButton     matlab.ui.control.Button
        importsoundLabel  matlab.ui.control.Label
        audioList         matlab.ui.control.ListBox
        importButton      matlab.ui.control.Button
        freq_plot         matlab.ui.control.UIAxes
        time_plot         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        list_items = [];
        file_id = 0;
        list_dir = strings;
        sound_selected = "";
        sound_select_id = [];
        result_sound = []; 
        audio_Data = {}; % y resampled
        length_Data = []; % length y
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.audioList.ItemsData=[];
            app.TextArea.Value = "Please import audiofiles.";
            app.addLamp.Color = [0.47,0.67,0.19];
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [file,path] = uigetfile({'*.wav','WAVE (*.wav)';'*.ogg','OGG (*ogg)';'*.flac','FLAC (*.flac)';'*.au','AU (*.au)';'*.aiff;*.aif','AIFF (*.aiff,*aif)' ...
                                        ;'*.aifc','AIFC (*aifc)';'*.mp3','MP3 (*.mp3)';'*.mp4a;*.mp4','MP4 (*mp4a,*mp4)';'*.wav;*.ogg;*.flac;*.au;*.aiff;*.aif;*.aifc;*.mp3;*.mp4a;*.mp4;' ...
                                        ,'all audiofiles (*.wav,*.ogg,*.flac,*.au,*.aiff,*.aif,*.aifc,*.mp3,*.mp4a,*.mp4)'},'MultiSelect','on'); 
            drawnow;
            figure(app.UIFigure);
            directory={};
             if(path ~= 0)
                if(~(ischar(file)))
                    for j =1:1:length(file)
                        directory{end+1} = [path file{j}];
                    end
                else
                    directory{end+1} = [path file];
                end
                for i = 1:1:length(directory)
               
                    app.list_dir(end+1) = directory{i};
                    app.list_items(end+1) = app.file_id+1;
                    app.file_id = app.file_id+1;
                    app.audioList.ItemsData = app.list_items;
                    if(~(ischar(file)))
                        app.audioList.Items(app.file_id) ={file{i}};
                    else
                         app.audioList.Items(app.file_id) ={file};
                    end
                    [y,originalFs] = audioread(directory{i});
                    sz = size(y);
                    if(sz(:,2)<2) % for mono
                        y = [y,y];
                    end
                    desiredFs = 48000;
                    [p,q] = rat(desiredFs / originalFs);
                    %originalFs
                    %originalFs*p/q
                    y_re = resample(y,p,q);
                    L = length(y_re);
                    app.audio_Data{end+1} = (y_re);
                    app.length_Data(end+1) = L;
                    app.TextArea.Value = "Please select audiofiles and press add button.";
                
                end
                
             end
            
        end

        % Value changed function: audioList
        function audioListValueChanged(app, event)
            value = app.audioList.Value; %value = selected file Ex. 1, 2, 3 
            selected_file = app.list_dir(value+1);   
            app.sound_selected = selected_file; % set sound_selected = what file that you pick in listBox
            app.sound_select_id = value;

        end

        % Button pushed function: soundOnButton
        function soundOnButtonPushed(app, event)
            if (~(isempty(app.result_sound)))
                sound(app.result_sound,48000);
            end
        end

        % Button pushed function: soundOffButton
        function soundOffButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

            maxlen = 0;
            if(~(isempty(app.sound_select_id)))
                app.addLamp.Color = [0,1,0];
                for j = 1:1:length(app.sound_select_id)
                    if(app.length_Data(app.sound_select_id(j))>maxlen)
                        maxlen = app.length_Data(app.sound_select_id(j));
                    end
                end
                %maxlen
                sum = zeros(maxlen,2);
                for i=1:1:length(app.sound_select_id)
                    y=app.audio_Data{app.sound_select_id(i)};
                    arr= y;
                    if(length(y)~=maxlen)
                        arr = cat(1,y,zeros(maxlen-length(y),2));
                    end
                    sum = sum+arr;
                end
                
                Textresult = "Summation of : ";
                for i = 1:1:length(app.sound_select_id)  
                    if(i < length(app.sound_select_id))
                        Textresult = append(Textresult,app.audioList.Items{app.sound_select_id(i)});
                        Textresult = append(Textresult," + ");
                    else
                        Textresult = append(Textresult,app.audioList.Items{app.sound_select_id(i)});
                    end
                end
                app.TextArea.Value = Textresult;
                app.result_sound= sum;
            else
                app.addLamp.Color = [0.47,0.67,0.19];
                app.TextArea.Value = "Please import audiofiles and press add button.";
            end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if(~(isempty(app.result_sound)))
                cla(app.freq_plot);
                cla(app.time_plot);
                result =app.result_sound;
                N = length(result);
                Fs = 48000;
                %duration = length(result)/Fs;
                
                Ts = 1/Fs;
                t = 0:Ts:(N-1)*Ts;
                plot(app.time_plot,t,result(:,1));
                hold (app.time_plot,'on');
                plot(app.time_plot,t,result(:,2));
                hold (app.time_plot,'off');
                legend(app.time_plot,'left','right');
                
                
                f = -Fs/2:Fs/(N-1):Fs/2;
                Y  = 1/N*fftshift(fft(result,N));% divided by N to normalise
               
                stem(app.freq_plot,f,abs(Y(:,1)),'Marker','none');
                hold (app.freq_plot,'on');
                stem(app.freq_plot,f,abs(Y(:,2)),'Marker','none');
                hold (app.freq_plot,'off');
                legend(app.freq_plot,'left','right');
                app.TextArea.Value = "Plot success!";
                app.addLamp.Color=[0.47,0.67,0.19];
            else 
                cla(app.freq_plot);
                cla(app.time_plot);
                app.TextArea.Value = "Please select audiofiles and press add button before plotting.";
            end
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.audioList.ItemsData = [];
            app.audioList.Items = {};
            app.list_items = [];
            app.file_id = 0;
            app.list_dir = strings;
            app.sound_selected = "";
            app.sound_select_id = [];
            app.result_sound = []; 
            app.audio_Data = {}; % y resampled
            app.length_Data = []; % length y
            cla(app.freq_plot);
            cla(app.time_plot);
            app.addLamp.Color=[0.47,0.67,0.19];
            app.TextArea.Value = "please import new audiofiles.";
        end

        % Button pushed function: time_popup
        function time_popupButtonPushed(app, event)
               if(~(isempty(app.result_sound))) 
                result =app.result_sound;
                figure('Name','Results in  Time-domain');
                N = length(result);
                Fs = 48000;
                Ts = 1/Fs;
                t = 0:Ts:(N-1)*Ts;
                plot(t,result(:,1));
                hold on;
                plot(t,result(:,2));
                xlabel('time (seconds)'); ylabel('Amplitude');grid ("on");
                hold off;
                legend('left','right');
               end
        end

        % Button pushed function: freq_popup
        function freq_popupButtonPushed(app, event)
              if(~(isempty(app.result_sound)))   
                result =app.result_sound;
                figure('Name','Results in  Frequency-domain');
                N = length(result);
                Fs = 48000;
                f = -Fs/2:Fs/(N-1):Fs/2;
                Y  = 1/N*fftshift(fft(result,N));% divided by N to normalise
                stem(f,abs(Y(:,1)),'Marker','none');
                hold on;
                stem(f,abs(Y(:,2)),'Marker','none');
                xlabel('frequency (Hz)'); ylabel('Magnitude|Y(f)|');grid ("on");
                hold off;
                legend('left','right');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.time_plot = uiaxes(app.UIFigure);
            title(app.time_plot, 'Time-Domain')
            xlabel(app.time_plot, 'time (seconds)')
            ylabel(app.time_plot, 'Amplitude')
            zlabel(app.time_plot, 'Z')
            app.freq_plot = uiaxes(app.UIFigure);
            title(app.freq_plot, 'Frequency-Domain')
            xlabel(app.freq_plot, 'frequency (Hz)')
            ylabel(app.freq_plot, 'Magnitude |Y(f)|')
            zlabel(app.freq_plot, 'Z')
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Icon = 'import.png';
            app.importButton.Text = '';
            app.audioList = uilistbox(app.UIFigure);
            app.audioList.Items = {};
            app.audioList.Multiselect = 'on';
            app.audioList.ValueChangedFcn = createCallbackFcn(app, @audioListValueChanged, true);
            app.audioList.Value = {};
            app.importsoundLabel = uilabel(app.UIFigure);
            app.importsoundLabel.Text = 'import sound';
            app.soundOnButton = uibutton(app.UIFigure, 'push');
            app.soundOnButton.ButtonPushedFcn = createCallbackFcn(app, @soundOnButtonPushed, true);
            app.soundOnButton.Icon = 'play-button-arrowhead.png';
            app.soundOnButton.Text = '';
            app.playsoundLabel = uilabel(app.UIFigure);
            app.playsoundLabel.Text = 'play sound';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.soundOffButton = uibutton(app.UIFigure, 'push');
            app.soundOffButton.ButtonPushedFcn = createCallbackFcn(app, @soundOffButtonPushed, true);
            app.soundOffButton.Icon = 'stop.png';
            app.soundOffButton.Text = '';
            app.stopsoundLabel = uilabel(app.UIFigure);
            app.stopsoundLabel.Text = 'stop sound';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.addLamp = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all ';
            app.time_popup = uibutton(app.UIFigure, 'push');
            app.time_popup.ButtonPushedFcn = createCallbackFcn(app, @time_popupButtonPushed, true);
            app.time_popup.Text = 'inspect time';
            app.freq_popup = uibutton(app.UIFigure, 'push');
            app.freq_popup.ButtonPushedFcn = createCallbackFcn(app, @freq_popupButtonPushed, true);
            app.freq_popup.Text = 'inspect frequency';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end